classdef GUI_swarm < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GUISwarmUIFigure                matlab.ui.Figure
        DronetypeButtonGroup            matlab.ui.container.ButtonGroup
        QuadcopterButton_2              matlab.ui.control.RadioButton
        PointmassButton                 matlab.ui.control.RadioButton
        StartsimulationSwitchLabel      matlab.ui.control.Label
        StartsimulationSwitch           matlab.ui.control.Switch
        NumberofagentsSpinnerLabel      matlab.ui.control.Label
        NumberofagentsSpinner           matlab.ui.control.Spinner
        ShowmapCheckBox                 matlab.ui.control.CheckBox
        DroneparametersLabel            matlab.ui.control.Label
        SwarmparametersLabel            matlab.ui.control.Label
        MapparametersLabel              matlab.ui.control.Label
        SwarmingalgorithmButtonGroup    matlab.ui.container.ButtonGroup
        VicsekButton                    matlab.ui.control.RadioButton
        OlfatiSaberButton               matlab.ui.control.RadioButton
        SimulationtimesEditFieldLabel   matlab.ui.control.Label
        SimulationtimesEditField        matlab.ui.control.NumericEditField
        SimulationparametersLabel       matlab.ui.control.Label
        Orientation180NKnobLabel        matlab.ui.control.Label
        Orientation180NKnob             matlab.ui.control.Knob
        DebugplotCheckBox               matlab.ui.control.CheckBox
        InteragentdistancemSliderLabel  matlab.ui.control.Label
        InteragentdistancemSlider       matlab.ui.control.Slider
        ReferencespeedmsSliderLabel     matlab.ui.control.Label
        ReferencespeedmsSlider          matlab.ui.control.Slider
    end

    properties (Access = public)
        sim_time = 40;                 % default as 100 seconds
        simulation = 0;                 
        drone_type = "quadcopter";                 
        nb_agents = 3;                  % number of drones in the simulation    
        
        wind = false;                   % default as false
        wind_gust = false;              % default as false
        wind_level = 0;                 % default as 0
        wind_gust_level = 0;            % default as 0
        
        active_environment = true;      % default as true
        
        swarming_algo = "vicsek";       % default as Vicsek
        orientation = 180;              % default as North
        d_ref = 10                       % default as 10  
        v_ref = 6                       % default as 6
        
        debug_plot = false;             % default as false
        new_init_pos_ned = 0;
        c_vm  = 3; %(CJ) 3 -> 6, 0.6
        a     = 1; %(CJ) 1 -> 2, 0.2
        b     = 5; %(CJ) 5 -> 10, 2.5
        delta = 0.2; %(CJ) 0.2 -> 4, 0.04
        k     = 2; %(CJ) 2 -> 4, 0.4
        r0 = 10; %(CJ) 10 -> 20, 2 ::: 25 is proper fix!
        lambda = 1; %(CJ) 1 -> 0.2              % (0,1]
        c_pm_obs = 5; %(CJ) 5 -> 10, 1

        max_a = 10; %(CJ) 10 -> 20, 2
        max_v = 7; %(CJ) 7 -> 14, 2
        
        r = 150;
        max_neig = 10;
        
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.VicsekButton.Enable = 'On';
            app.OlfatiSaberButton.Enable = 'On';
            app.NumberofagentsSpinner.Enable = 'On';
            app.nb_agents = 3;
            app.ShowmapCheckBox.Enable = 'On';
            app.Orientation180NKnob.Enable = 'On';
            app.InteragentdistancemSlider.Enable = 'On';
            app.ReferencespeedmsSlider.Enable = 'On';
        end

        % Selection changed function: DronetypeButtonGroup
        function DronetypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.DronetypeButtonGroup.SelectedObject;
            switch selectedButton.Text  
                case 'Quadcopter'
                    app.drone_type = "quadcopter";
                case 'Point-mass'
                    app.drone_type = "point_mass";
            end
        end

        % Value changed function: StartsimulationSwitch
        function StartsimulationSwitchValueChanged(app, event)
            value = app.StartsimulationSwitch.Value;
            switch value
                case 'On'
%                     app.NumberofagentsSpinner.Enable = 'Off';
%                     app.QuadcopterButton_2.Enable = 'Off';
%                     app.FixedwingButton.Enable = 'Off';
%                     app.FilletButton.Enable = 'Off';
%                     app.DubinButton.Enable = 'Off';
%                     app.DubinCityButton.Enable = 'Off';
%                     app.StraightRRTButton.Enable = 'Off';
%                     app.CoverRRTDubinButton.Enable = 'Off';
                    switch app.swarming_algo
                        case "vicsek"
                            example_vicsek(); 
                        case "olfati_saber"
                            
                            randomtest = 0;
                            % when you do rt, 
                            % randomtest is here, example_olfati_saber.m,
                            % and param_swarm.m
                                
                            if randomtest
                                for idx_sim = 1 : num_sim
                                    fprintf('this is %i th iteration \n', idx_sim);
                                    
                                    fid_c = fopen('crash_rt.txt', 'at') ;
                                    fprintf(fid_c, 'this is %i th iteration \n', idx_sim);
                                    fclose(fid_c) ;
                                    
                                    dlmwrite('Dcc.csv',idx_sim,'delimiter',',','-append');
                                    dlmwrite('dist.csv',idx_sim,'delimiter',',','-append');
                                    dlmwrite('position.csv',idx_sim,'delimiter',',','-append');
                                    
                                    
                                    fid = fopen('crash_rt.txt', 'at') ;
                                    
                                    fprintf(fid, 'this is %i th iteration \n', idx_sim) ;
                                    fclose(fid) ;
                                    
                                    coor_d1 = readmatrix('data/1st_drone_agent_2_ran_gen.csv');
                                    coor_d2 = readmatrix('data/2nd_drone_agent_1_ran_gen.csv');
                                    coor_d3 = readmatrix('data/3rd_drone_agent_3_ran_gen.csv');
                                    coor_d4 = readmatrix('data/4.csv');
                                    coor_d5 = readmatrix('data/5.csv');
                                    coor_d6 = readmatrix('data/6.csv');
                                    coor_d7 = readmatrix('data/7.csv');
                                    coor_d8 = readmatrix('data/8.csv');
                                    coor_d9 = readmatrix('data/9.csv');
                                    coor_d10 = readmatrix('data/10.csv');
                                    coor_d11 = readmatrix('data/11.csv');
                                    coor_d12 = readmatrix('data/12.csv');
                                    coor_d13 = readmatrix('data/13.csv');
                                    coor_d14 = readmatrix('data/14.csv');
                                    coor_d15 = readmatrix('data/15.csv');
                                    coor_d16 = readmatrix('data/16.csv');
                                    coor_d17 = readmatrix('data/17.csv');
                                    coor_d18 = readmatrix('data/18.csv');
                                    coor_d19 = readmatrix('data/19.csv');
                                    coor_d20 = readmatrix('data/20.csv');
                                
                                    app.new_init_pos_ned = [coor_d1(idx_sim,:)',coor_d2(idx_sim,:)',coor_d3(idx_sim,:)',coor_d4(idx_sim,:)',coor_d5(idx_sim,:)',coor_d6(idx_sim,:)',coor_d7(idx_sim,:)',coor_d8(idx_sim,:)',coor_d9(idx_sim,:)',coor_d10(idx_sim,:)',coor_d11(idx_sim,:)',coor_d12(idx_sim,:)',coor_d13(idx_sim,:)',coor_d14(idx_sim,:)',coor_d15(idx_sim,:)',coor_d16(idx_sim,:)',coor_d17(idx_sim,:)',coor_d18(idx_sim,:)',coor_d19(idx_sim,:)',coor_d20(idx_sim,:)'];
                                    
                                    %%%%%%%%%%%%%%%%%%%%%%%%%5
                                    
                                    disp("###### TIME start######");
                                    disp(datestr(now,'HH:MM:SS.FFF'));
                                    example_olfati_saber();
                                    disp(datestr(now,'HH:MM:SS.FFF'));
                                    disp("###### TIME end######");
                                    
                                end
                            else


                                for order = 16:16 %[1,2]%,3,4,5,6,7,8,9,10,11,12,13,14,15,16,17,18,19,20]
                                    app.c_vm  = 3; %(CJ) 3 -> 6, 0.6
                                    app.a     = 1; %(CJ) 1 -> 2, 0.2
                                    app.b     = 5; %(CJ) 5 -> 10, 2.5
                                    app.delta = 0.2; %(CJ) 0.2 -> 4, 0.04
                                    app.k     = 2; %(CJ) 2 -> 4, 0.4
                                    app.r0 = 10; %(CJ) 10 -> 20, 2 ::: 25 is proper fix!
                                    app.lambda = 1; %(CJ) 1 -> 0.2              % (0,1]
                                    app.c_pm_obs = 5; %(CJ) 5 -> 10, 1
        
                                    app.max_a = 10; %(CJ) 10 -> 20, 2
                                    app.max_v = 7; %(CJ) 7 -> 14, 2
                                    
                                    app.r = 150;
                                    app.d_ref = 10;
                                    app.v_ref = 6;
                                    app.max_neig = 10;
                                    
                                    fprintf('this is %i th order \n', order);
                                    
                                    num_sim = 10;
                                    
                                    switch(order)
                                        case 1
                                            app.c_vm  = 0.6; %(CJ) 3 -> 6, 0.6
                                            
                                        case 2
                                            app.c_vm  = 6; %(CJ) 3 -> 6, 0.6
                                        case 3
                                            app.a     = 0.2; %(CJ) 1 -> 2, 0.2
                                        case 4
                                            app.a     = 2; %(CJ) 1 -> 2, 0.2
                                        case 5
                                            app.b     = 2.5; %(CJ) 5 -> 10, 2.5
                                        case 6
                                            app.b     = 10; %(CJ) 5 -> 10, 2.5
                                        case 7
                                            app.delta = 0.04; %(CJ) 0.2 -> 0.4, 0.04
                                        case 8
                                            app.delta = 0.4; %(CJ) 0.2 -> 0.4, 0.04
                                        case 9
                                            app.k     = 0.4; %(CJ) 2 -> 4, 0.4
                                        case 10
                                            app.k     = 4; %(CJ) 2 -> 4, 0.4
                                        case 11
                                            app.r0 = 2; %(CJ) 10 -> 20, 2 ::: 25 is proper fix!
                                        case 12
                                            app.r0 = 20; %(CJ) 10 -> 20, 2 ::: 25 is proper fix!
                                        case 13
                                            app.lambda = 0.2; %(CJ) 1 -> 0.2              % (0,1]
                                        case 14
                                            app.lambda = 2; %(CJ) 1 -> 0.2              % (0,1]
                                        case 15
                                            app.c_pm_obs = 1; %(CJ) 5 -> 10, 1
                                        case 16
                                            app.c_pm_obs = 10; %(CJ) 5 -> 10, 1
                                        case 17
                                            app.max_a = 2; %(CJ) 10 -> 20, 2
                                        case 18
                                            app.max_a = 20; %(CJ) 10 -> 20, 2
                                        case 19
                                            app.max_v = 2; %(CJ) 7 -> 14, 2
                                        case 20
                                            app.max_v = 14; %(CJ) 7 -> 14, 2
                                        
                                        case 21
                                            app.r = 30;
                                        case 22
                                            app.r = 300;
                                        case 23
                                            app.d_ref = 2;
                                        case 24
                                            app.d_ref = 20;
                                        case 25
                                            app.v_ref = 1.2;
                                        case 26
                                            app.v_ref = 12;
                                        case 27
                                            app.max_neig = 2;
                                        case 28
                                            app.max_neig = 20;
                                    end
                                    
                                    for idx_sim = 1 : num_sim
                                        
                                        fprintf('this is %i th iteration \n', idx_sim);
                                        
                                        fid_c = fopen('crash_rt.txt', 'at') ;
%                                         fprintf(fid_c, 'this is %i th order \n', order);
                                        fprintf(fid_c, 'this is %i th iteration \n', idx_sim);
                                        fclose(fid_c) ;
                                        
                                        dlmwrite('Dcc.csv',idx_sim,'delimiter',',','-append');
                                        dlmwrite('dist.csv',idx_sim,'delimiter',',','-append');
                                        dlmwrite('position.csv',idx_sim,'delimiter',',','-append');
                                        
                                        %%%%%%%%%%%%%%%%%%%%%%%%%5

                                        disp("###### TIME start######");
                                        disp(datestr(now,'HH:MM:SS.FFF'));
                                        example_olfati_saber();
                                        disp(datestr(now,'HH:MM:SS.FFF'));
                                        disp("###### TIME end######");
                                    
                                    end 
                                    
                                end
                            end
                        end
                case 'Off'
%                     app.NumberofagentsSpinner.Enable = 'On';
%                     app.QuadcopterButton_2.Enable = 'On';
%                     app.FixedwingButton.Enable = 'On';
%                     app.FilletButton.Enable = 'On';
%                     app.DubinButton.Enable = 'On';
%                     app.DubinCityButton.Enable = 'On';
%                     app.StraightRRTButton.Enable = 'On';
%                     app.CoverRRTDubinButton.Enable = 'On';
%                     close all;
                    
            end
        end

        % Value changed function: NumberofagentsSpinner
        function NumberofagentsSpinnerValueChanged(app, event)
            app.nb_agents = app.NumberofagentsSpinner.Value;
        end

        % Callback function
        function AddwindCheckBoxValueChanged(app, event)
            app.wind = app.AddwindCheckBox.Value;
            if app.wind == false
                app.WindSlider.Enable = 'Off';
            else
                app.WindSlider.Enable = 'On';
            end
        end

        % Value changed function: ShowmapCheckBox
        function ShowmapCheckBoxValueChanged(app, event)
            app.active_environment = app.ShowmapCheckBox.Value;
        end

        % Callback function
        function WindSliderValueChanged(app, event)
            app.wind_level = app.WindSlider.Value;
        end

        % Callback function
        function AddgustsCheckBoxValueChanged(app, event)
            app.wind_gust = app.AddgustsCheckBox.Value;
            if app.wind_gust == false
                app.WindSlider_2.Enable = 'Off';
            else
                app.WindSlider_2.Enable = 'On';
            end
        end

        % Callback function
        function WindSlider_2ValueChanged(app, event)
            app.wind_gust_level = app.WindSlider_2.Value;
        end

        % Selection changed function: SwarmingalgorithmButtonGroup
        function SwarmingalgorithmButtonGroupSelectionChanged(app, event)
            selectedButton = app.SwarmingalgorithmButtonGroup.SelectedObject;
            switch selectedButton.Text  
                case 'Vicsek'
                    app.swarming_algo = "vicsek";
                case 'Olfati-Saber'
                    app.swarming_algo = "olfati_saber";
            end
        end

        % Value changed function: SimulationtimesEditField
        function SimulationtimesEditFieldValueChanged(app, event)
            app.sim_time = app.SimulationtimesEditField.Value;
        end

        % Callback function
        function SimulationButtonGroupSelectionChanged(app, event)
            selectedButton = app.SimulationButtonGroup.SelectedObject;
            switch selectedButton.Text  
                case 'Vicsek'
                    app.simulation = 0;
                    app.VicsekButton.Enable = 'On';
                    app.OlfatiSaberButton.Enable = 'On';
                    app.NumberofagentsSpinner.Enable = 'On';
                    app.nb_agents = app.NumberofagentsSpinner.Value;
                    app.ShowmapCheckBox.Enable = 'On';
                    app.Orientation180NKnob.Enable = 'On';
                    app.d_ref = app.InteragentdistancemSlider.Value;
                    app.v_ref = app.ReferencespeedmsSlider.Value;
                case 'Olfati-Saber'
                    app.simulation = 1;
                    app.VicsekButton.Enable = 'Off';
                    app.OlfatiSaberButton.Enable = 'Off';
                    app.NumberofagentsSpinner.Enable = 'On';
                    app.nb_agents = app.NumberofagentsSpinner.Value;
                    app.ShowmapCheckBox.Enable = 'On';
                    app.Orientation180NKnob.Enable = 'Off';
                    app.d_ref = app.InteragentdistancemSlider.Value;
                    app.v_ref = app.ReferencespeedmsSlider.Value;
            end
        end

        % Value changed function: Orientation180NKnob
        function Orientation180NKnobValueChanged(app, event)
            app.orientation = app.Orientation180NKnob.Value;
        end

        % Value changing function: Orientation180NKnob
        function Orientation180NKnobValueChanging(app, event)
            app.orientation = event.Value;
        end

        % Value changed function: DebugplotCheckBox
        function DebugplotCheckBoxValueChanged(app, event)
            app.debug_plot = app.DebugplotCheckBox.Value;
        end

        % Value changed function: InteragentdistancemSlider
        function InteragentdistancemSliderValueChanged(app, event)
            app.d_ref = app.InteragentdistancemSlider.Value;
        end

        % Value changed function: ReferencespeedmsSlider
        function ReferencespeedmsSliderValueChanged(app, event)
            app.v_ref = app.ReferencespeedmsSlider.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GUISwarmUIFigure = uifigure('Visible', 'off');
            app.GUISwarmUIFigure.Name = 'GUI Swarm';
            app.DronetypeButtonGroup = uibuttongroup(app.GUISwarmUIFigure);
            app.DronetypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DronetypeButtonGroupSelectionChanged, true);
            app.DronetypeButtonGroup.Title = 'Drone type';
            app.QuadcopterButton_2 = uiradiobutton(app.DronetypeButtonGroup);
            app.QuadcopterButton_2.Text = 'Quadcopter';
            app.QuadcopterButton_2.Value = true;
            app.PointmassButton = uiradiobutton(app.DronetypeButtonGroup);
            app.PointmassButton.Text = 'Point-mass';
            app.StartsimulationSwitchLabel = uilabel(app.GUISwarmUIFigure);
            app.StartsimulationSwitchLabel.Text = 'Start simulation';
            app.StartsimulationSwitch = uiswitch(app.GUISwarmUIFigure, 'slider');
            app.StartsimulationSwitch.ValueChangedFcn = createCallbackFcn(app, @StartsimulationSwitchValueChanged, true);
            app.NumberofagentsSpinnerLabel = uilabel(app.GUISwarmUIFigure);
            app.NumberofagentsSpinnerLabel.Text = 'Number of agents';
            app.NumberofagentsSpinner = uispinner(app.GUISwarmUIFigure);
            app.NumberofagentsSpinner.Limits = [1 50];
            app.NumberofagentsSpinner.ValueChangedFcn = createCallbackFcn(app, @NumberofagentsSpinnerValueChanged, true);
            app.NumberofagentsSpinner.Value = 3;
            app.ShowmapCheckBox = uicheckbox(app.GUISwarmUIFigure);
            app.ShowmapCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowmapCheckBoxValueChanged, true);
            app.ShowmapCheckBox.Text = 'Show map';
            app.ShowmapCheckBox.Value = true;
            app.DroneparametersLabel = uilabel(app.GUISwarmUIFigure);
            app.DroneparametersLabel.Text = 'Drone parameters';
            app.SwarmparametersLabel = uilabel(app.GUISwarmUIFigure);
            app.SwarmparametersLabel.Text = 'Swarm parameters';
            app.MapparametersLabel = uilabel(app.GUISwarmUIFigure);
            app.MapparametersLabel.Text = 'Map parameters';
            app.SwarmingalgorithmButtonGroup = uibuttongroup(app.GUISwarmUIFigure);
            app.SwarmingalgorithmButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SwarmingalgorithmButtonGroupSelectionChanged, true);
            app.SwarmingalgorithmButtonGroup.Title = 'Swarming algorithm';
            app.VicsekButton = uiradiobutton(app.SwarmingalgorithmButtonGroup);
            app.VicsekButton.Text = 'Vicsek';
            app.VicsekButton.Value = true;
            app.OlfatiSaberButton = uiradiobutton(app.SwarmingalgorithmButtonGroup);
            app.OlfatiSaberButton.Text = 'Olfati-Saber';
            app.SimulationtimesEditFieldLabel = uilabel(app.GUISwarmUIFigure);
            app.SimulationtimesEditFieldLabel.Text = 'Simulation time [s]';
            app.SimulationtimesEditField = uieditfield(app.GUISwarmUIFigure, 'numeric');
            app.SimulationtimesEditField.Limits = [0 Inf];
            app.SimulationtimesEditField.ValueChangedFcn = createCallbackFcn(app, @SimulationtimesEditFieldValueChanged, true);
            app.SimulationtimesEditField.Value = 40;
            app.SimulationparametersLabel = uilabel(app.GUISwarmUIFigure);
            app.SimulationparametersLabel.Text = 'Simulation parameters';
            app.Orientation180NKnobLabel = uilabel(app.GUISwarmUIFigure);
            app.Orientation180NKnobLabel.Text = 'Orientation (180=N)';
            app.Orientation180NKnob = uiknob(app.GUISwarmUIFigure, 'continuous');
            app.Orientation180NKnob.Limits = [0 360];
            app.Orientation180NKnob.MajorTicks = [0 90 180 270 360];
            app.Orientation180NKnob.MajorTickLabels = {'South', 'West', 'North', 'East', 'South'};
            app.Orientation180NKnob.ValueChangedFcn = createCallbackFcn(app, @Orientation180NKnobValueChanged, true);
            app.Orientation180NKnob.ValueChangingFcn = createCallbackFcn(app, @Orientation180NKnobValueChanging, true);
            app.Orientation180NKnob.Value = 180;
            app.DebugplotCheckBox = uicheckbox(app.GUISwarmUIFigure);
            app.DebugplotCheckBox.ValueChangedFcn = createCallbackFcn(app, @DebugplotCheckBoxValueChanged, true);
            app.DebugplotCheckBox.Text = 'Debug plot';
            app.InteragentdistancemSliderLabel = uilabel(app.GUISwarmUIFigure);
            app.InteragentdistancemSliderLabel.Text = 'Inter-agent distance [m]';
            app.InteragentdistancemSlider = uislider(app.GUISwarmUIFigure);
            app.InteragentdistancemSlider.Limits = [5 15];
            app.InteragentdistancemSlider.MajorTicks = [5 7 10 12.5 15];
            app.InteragentdistancemSlider.MajorTickLabels = {'5', '7,5', '10', '12.5', '15'};
            app.InteragentdistancemSlider.ValueChangedFcn = createCallbackFcn(app, @InteragentdistancemSliderValueChanged, true);
            app.InteragentdistancemSlider.Value = 10;
            app.ReferencespeedmsSliderLabel = uilabel(app.GUISwarmUIFigure);
            app.ReferencespeedmsSliderLabel.Text = 'Reference speed [m/s]';
            app.ReferencespeedmsSlider = uislider(app.GUISwarmUIFigure);
            app.ReferencespeedmsSlider.Limits = [2 8];
            app.ReferencespeedmsSlider.MajorTicks = [2 4 6 8 10];
            app.ReferencespeedmsSlider.ValueChangedFcn = createCallbackFcn(app, @ReferencespeedmsSliderValueChanged, true);
            app.ReferencespeedmsSlider.Value = 6;
            % Show the figure after all components are created
            app.GUISwarmUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_swarm

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GUISwarmUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GUISwarmUIFigure)
        end
    end
end